classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        Switch3       matlab.ui.control.Switch
        Switch3Label  matlab.ui.control.Label
        Switch2       matlab.ui.control.Switch
        Switch2Label  matlab.ui.control.Label
        Switch        matlab.ui.control.Switch
        SwitchLabel   matlab.ui.control.Label
        UIAxes        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
         % Description
         % exmaple data  (data is not lable)
       dataX = [1,2,3,4,5,6,7,8,9,10]
       dataY = [1,2,3,4,5,6,7,8,9,10]
       plot1,plot2
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value1 = app.Switch.Value;
            if value1 == true
                % Store the plot handle in the property
                app.plot1 = plot(app.UIAxes, app.dataX, app.dataY);
                hold(app.UIAxes, "on");
            else
                % Clear the UIAxes when the switch is turned off
                if ishandle(app.plot1)
                    delete(app.plot1);
                end
            end
        end

        % Value changed function: Switch2
        function Switch2ValueChanged(app, event)
            value2 = app.Switch2.Value;
            if value2 == true
                invertedList = app.dataY(end:-1:1);
                % Store the plot handle for plot2 in the property
                app.plot2 = plot(app.UIAxes, invertedList, app.dataX);
            else  
                % Clear the UIAxes when the switch is turned off
                if ishandle(app.plot2)
                    delete(app.plot2);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Graph')
            xlabel(app.UIAxes, 'Frequency')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ItemsData = [false true];
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = false;
            app.Switch2Label = uilabel(app.UIFigure);
            app.Switch2Label.Text = 'Switch2';
            app.Switch2 = uiswitch(app.UIFigure, 'slider');
            app.Switch2.ItemsData = [false true];
            app.Switch2.ValueChangedFcn = createCallbackFcn(app, @Switch2ValueChanged, true);
            app.Switch2.Value = false;
            app.Switch3Label = uilabel(app.UIFigure);
            app.Switch3Label.Text = 'Switch3';
            app.Switch3 = uiswitch(app.UIFigure, 'slider');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end